classdef ProGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TimeSliderLabel                matlab.ui.control.Label
        TimeSlider                     matlab.ui.control.Slider
        RecordButton                   matlab.ui.control.Button
        Lamp                           matlab.ui.control.Lamp
        UIAxes                         matlab.ui.control.UIAxes
        NoiseCancellationLabel         matlab.ui.control.Label
        PlayNoisecancelledSoundButton  matlab.ui.control.Button
        UIAxes_2                       matlab.ui.control.UIAxes
        PlayOriginalSoundButton        matlab.ui.control.Button
        FilteringdoneusingPanel        matlab.ui.container.Panel
        LMSButton                      matlab.ui.control.Button
        NLMSButton                     matlab.ui.control.Button
        RLSButton                      matlab.ui.control.Button
        EditField                      matlab.ui.control.EditField
        UIAxes2                        matlab.ui.control.UIAxes
        PlayNoiseaddedSoundButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        dd; % Description
        
        mm;
        Nadded;
        NoiseSoundD;
        LMSfiltered;
        RLSfiltered;
        NLMSfiltered;
        uu;
        goodSignal;
    end
    
    
    methods (Access = public)
        
        function [Sounddata] = soundRec(app,time)
        %UNTITLED Summary of this function goes here
        %   Detailed explanation goes here
        Fs = 8000;
        %[d,Fs] = audioread('music.wav'); % Loading of input signal
        recording = audiorecorder;
        disp('Recording....');
        recordblocking(recording,time); %Recording sound for 10 seconds 
        Sounddata =  getaudiodata(recording);
        end
        %Noise added Signal
        function [Nadded]=soundAdded(app,d)
            d = d / rms(d, 1);                % Normalization of the signal
            m = length(d);  % length of the signal
            app.mm=m ;
            %% Creating Noise(White Gaussian Noise)
            reference_signal = wgn(m,1,8); % white gaussian noise with the length of the input signal
            %% Designing digital filter
            FIR_fil = fir1(12, 0.6);              % Designing a FIR filter for adjusting weigts
            u = filter(FIR_fil, 1, reference_signal);        % Filtering the reference signal
            %% Addition of noise to the input signal
            app.NoiseSoundD = d + u;    % signal to be filtered
            Nadded=app.NoiseSoundD;
        end
        %LMS
        function [E]=soundLMS(app,d)
            
            %sound(d)
            d = d / rms(d, 1);                % Normalization of the signal
            m = length(d);  % length of the signal
            app.mm=m ;
            %% Creating Noise(White Gaussian Noise)
            reference_signal = wgn(m,1,8); % white gaussian noise with the length of the input signal
            %% Designing digital filter
            FIR_fil = fir1(12, 0.6);              % Designing a FIR filter for adjusting weigts
            u = filter(FIR_fil, 1, reference_signal);        % Filtering the reference signal
            %% Addition of noise to the input signal
            app.NoiseSoundD = d + u;    % signal to be filtered
            
            %soundsc(app.NoiseSoundD,8000);
            order = 12;       % order of the filter(maximum no.of delay elements)
            mu = 0.0003642;     % It is constant(optimal value of mu value is calculated using experiments)
            n = length(app.NoiseSoundD);     %length of noised_signal signal
            w = zeros(order,1);      %initializing vectors
            E = zeros(1,m);
            for k = 12:n
             U = u(k-11:k);
             y = U'*w;                % preliminary output signal 
             E(k) = app.NoiseSoundD(k)-y;     % error
             w = w + mu*E(k)*U;       % calculating LMS fiter weights
            end
            app.goodSignal=E;
            
            sound(E);
            
            t=(1:app.mm)';
            plot(app.UIAxes,t,app.dd);
            plot(app.UIAxes2,t,app.NoiseSoundD);
            plot(app.UIAxes_2,t,app.goodSignal);
        end
        %NLMS
        function [NE]=soundNLMS(app,d)
            d = d / rms(d, 1);                % Normalization of the signal
            %% Corrupting Audio Signal with noise
            M = 4; % Filter length
            FIR_fil = fir1(M, 0.6);              % Designing a FIR filter for adjusting weigts
            %Noise is added to the uncorrupted signal. 
            noise = wgn(length(d),1,10); % A gussian noise of power 10dbw is added to the audio input inorder to corrupt it
            reference_signal = d + noise; % Uncorrupted sound + noise
            app.NoiseSoundD=reference_signal;
            u = filter(FIR_fil, 1, reference_signal);
            disp('Playing corrupted sound');
            %sound(x,Fs,bits_per_sample) %Playing the corrupted sound
            %pause(5)
            
            %% NLMS parameters
            mu = 0.0003642; % Step size
            epsilon = 0.1; % Bias (any samll positive constant)
            w = zeros(M,1);
            error = zeros(M,1); %error signal
            for i = M:length(reference_signal)
             U = u(i-(M-1):i);
             K = mu/(abs(epsilon+(d(i)^2))); % Difference between lms and nlms
             y = U'*w;                % preliminary output signal 
             NE(i) = d(i)-y;     % error
             w = w + K*NE(i)*U;       % calculating NLMS fiter weights
                end
        app.goodSignal=NE;
        sound(NE);
        t=(1:app.mm)';
            plot(app.UIAxes,t,app.dd);
            plot(app.UIAxes2,t,app.NoiseSoundD);
            plot(app.UIAxes_2,t,app.goodSignal);
        end
        %RLS
        function[Rls]=soundRL(app,d)
            %sound(d)
            noise = wgn(length(d),1,10); % A gussian noise of power 10dbw is added to the audio input inorder to corrupt it
            x = d + noise; % Uncorrupted sound + noise
            
            %sound(x,Fs,bits_per_sample) %Playing the corrupted sound
            
            %% RLS Filter Implementation
            %% Parameter List
            % d - Reference signal
            % x - Noissy signal
            % lamda - Forgetting factor
            % delta - Large positive quantity
            % Rn - Covariance Matrix
            %%
            M = 80; % Length of FIR Filter
            w = zeros(M,1); %Initialize filter weights to zero
            delta = 100; %Large positive constant
            Rn = eye(M)*delta; %Initialization of the covariance matrix
            y = zeros(length(d),1); %output signal
            error = zeros(length(d),1); % error signal
            x_ = zeros(length(d),1);
            lambda = 0.999;
            F = rand(length(d),1);
            for i = 1 : length( d )
            f( 1 : i ) = flipud( F( 1 : i ) );     
             if length( f ) < M 
             f( i + 1 : M, 1 ) = 0;
             elseif length( f ) > M            %Implementation of the rls Algorithm
             f = f( 1 : M );
             end 
            K = ( Rn * f ) ./ ( lambda + (f' * Rn * f ));
            e( i ) = d( i ) - (f' * w);
            w= w + (K * e( i ));
            Rn     = ( lambda^-1 * Rn ) - ( lambda^-1 * K * f' * Rn );
            error(i)=(e(i)-d(i)).^2;
            
            end
            Rls=e;
            app.goodSignal=Rls;
            t=(1:app.mm)';
            plot(app.UIAxes,t,app.dd);
            plot(app.UIAxes2,t,app.NoiseSoundD);
            plot(app.UIAxes_2,t,app.goodSignal);
    end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            app.Lamp.Color=[0,1,0];
            app.dd=app.soundRec(app.TimeSlider.Value);
            app.Lamp.Color=[0,0,0];
            app.EditField.Value="Recording...";
            
        end

        % Button pushed function: PlayOriginalSoundButton
        function PlayOriginalSoundButtonPushed(app, event)
            app.EditField.Value="Playing original signal";
            
            sound(app.dd);
            
        end

        % Button pushed function: LMSButton
        function LMSButtonPushed(app, event)
            app.EditField.Value="Playing filtered signal using LMS";
            %soundsc(app.NAddedd,8000);
                app.LMSfiltered=app.soundLMS(app.dd);
            soundsc(app.LMSfiltered,8000);
        end

        % Button pushed function: NLMSButton
        function NLMSButtonPushed(app, event)
            %app.NFd=app.soundLMS(app.mm,app.NSd);
                app.EditField.Value="Playing filtered signal using NLMS";
                app.NLMSfiltered=app.soundNLMS(app.dd);
            soundsc(app.NLMSfiltered,8000);
                
        end

        % Button pushed function: RLSButton
        function RLSButtonPushed(app, event)
            %app.NFd=app.soundLMS(app.mm,app.NSd);
                app.EditField.Value="Played filtered signal using RLS";
                app.RLSfiltered=app.soundRL(app.dd);
            soundsc(app.RLSfiltered,8000);
                
        end

        % Button pushed function: PlayNoiseaddedSoundButton
        function PlayNoiseaddedSoundButtonPushed(app, event)
            app.EditField.Value="Play Noise White gaussian added signal ";
                app.Nadded=app.soundAdded(app.dd);
            soundsc(app.Nadded,8000);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TimeSliderLabel = uilabel(app.UIFigure);
            app.TimeSliderLabel.Text = 'Time ';
            app.TimeSlider = uislider(app.UIFigure);
            app.TimeSlider.Limits = [0 30];
            app.TimeSlider.Value = 5;
            app.RecordButton = uibutton(app.UIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = {'Record'; ''};
            app.Lamp = uilamp(app.UIFigure);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Signal')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.NoiseCancellationLabel = uilabel(app.UIFigure);
            app.NoiseCancellationLabel.Text = 'Noise Cancellation';
            app.PlayNoisecancelledSoundButton = uibutton(app.UIFigure, 'push');
            app.PlayNoisecancelledSoundButton.Text = 'Play Noise cancelled Sound';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Noise Cancelled Signal')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.PlayOriginalSoundButton = uibutton(app.UIFigure, 'push');
            app.PlayOriginalSoundButton.ButtonPushedFcn = createCallbackFcn(app, @PlayOriginalSoundButtonPushed, true);
            app.PlayOriginalSoundButton.Text = 'Play Original Sound';
            app.FilteringdoneusingPanel = uipanel(app.UIFigure);
            app.FilteringdoneusingPanel.Title = 'Filtering done using';
            app.LMSButton = uibutton(app.FilteringdoneusingPanel, 'push');
            app.LMSButton.ButtonPushedFcn = createCallbackFcn(app, @LMSButtonPushed, true);
            app.LMSButton.Text = 'LMS';
            app.NLMSButton = uibutton(app.FilteringdoneusingPanel, 'push');
            app.NLMSButton.ButtonPushedFcn = createCallbackFcn(app, @NLMSButtonPushed, true);
            app.NLMSButton.Text = 'NLMS';
            app.RLSButton = uibutton(app.FilteringdoneusingPanel, 'push');
            app.RLSButton.ButtonPushedFcn = createCallbackFcn(app, @RLSButtonPushed, true);
            app.RLSButton.Text = 'RLS';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Corrupted Signal')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.PlayNoiseaddedSoundButton = uibutton(app.UIFigure, 'push');
            app.PlayNoiseaddedSoundButton.ButtonPushedFcn = createCallbackFcn(app, @PlayNoiseaddedSoundButtonPushed, true);
            app.PlayNoiseaddedSoundButton.Text = 'Play Noise added Sound';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end